classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AxisSelectionsPanel  matlab.ui.container.Panel
        XAxisDropDownLabel   matlab.ui.control.Label
        XAxisDropDown        matlab.ui.control.DropDown
        YAxisDropDownLabel   matlab.ui.control.Label
        YAxisDropDown        matlab.ui.control.DropDown
        TabGroup             matlab.ui.container.TabGroup
        PlotTab              matlab.ui.container.Tab
        UIAxes               matlab.ui.control.UIAxes
        DataTab              matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        MetadataTab          matlab.ui.container.Tab
        UITable2             matlab.ui.control.Table
    end

    
    properties (Access = private)
        Data
        x_data
        y_data
        
        metaData
    end
    
    methods (Access = private)
        
        % plots data based on most recent dropdown values
        function refreshPlot(app)
            
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            
            scatter(app.UIAxes,app.x_data,app.y_data)
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            % Loads in Data
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the data file.'});
            DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
            %colNames = DensityRead.Properties.VariableNames;
            % app.XAxisDropDown.Items = DensityRead.Properties.VariableNames
            % app.YAxisDropDown.Items = DensityRead.Properties.VariableNames
                    
            app.Data = DensityRead;
            app.UITable.Data = app.Data; % ultimately, the data tab is optional as it will not display the entirety of the data once there are >2 pmts
            
            
            % Loads in MetaData
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the data file.'});
            DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
            
            app.metaData = DensityRead;
            app.UITable2.Data = app.metaData;
            
            
            refreshPlot(app)
            
            
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'bm', 'pmt1', 'pmt2'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'bm';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'bm', 'pmt1', 'pmt2'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'bm';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MetadataTab = uitab(app.TabGroup);
            app.MetadataTab.Title = 'Metadata';
            app.UITable2 = uitable(app.MetadataTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end